classdef Video_AT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        ATEditFieldLabel    matlab.ui.control.Label
        Edit_AT_min         matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        Edit_AT_max         matlab.ui.control.NumericEditField
        VideoButton         matlab.ui.control.Button
        LoadButton          matlab.ui.control.Button
        Switch              matlab.ui.control.Switch
        ParamDropDownLabel  matlab.ui.control.Label
        ParamDropDown       matlab.ui.control.DropDown
        FPSEditFieldLabel   matlab.ui.control.Label
        FPSEditField        matlab.ui.control.NumericEditField
        QualEditFieldLabel  matlab.ui.control.Label
        QualEditField       matlab.ui.control.NumericEditField
        LightDropDown       matlab.ui.control.DropDown
        RotationCheckBox    matlab.ui.control.CheckBox
        ColorbarCheckBox    matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        mat = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file
            V = load([fileparts(absPath),filesep,'GUI_egm_mFiles',filesep,'Geo_Chann',filesep,'colormap_carto']);
            app.mat.cmap = V.cmap;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
%             folder = fileparts(which('Video_AT.mlapp'));
%             addpath([absPath,filesep,'Geo_Chann'])

            
            clear folder
            [n,p] = uigetfile('*.mat','Load matlab file');
            app.mat.filepath = p;
            app.mat.filename = n;
            V = load([p,n]);
            app.mat.V = V;
            
            cc = findall(app.UIFigure,'type','light');
            delete(cc)
            
            tri = V.geo.Mesh_ventricles;
            v = V.geo.Nodes_ventricles;
            switch app.ParamDropDown.Value
                case 'AT-from ATmin'
                    x = V.MarkersC.dt - min(V.MarkersC.dt); % AT from min AT
                    app.mat.cb_title = 'AT (ms)';
                case 'AT-from spike'
                    x = V.MarkersC.dt - V.spikes(1); % AT from spike
                    app.mat.cb_title = 'AT (ms)';
                case 'RT'
                    x = V.MarkersC.dt - V.spikes(1); % RT from spike
                    app.mat.cb_title = 'RT (ms)';
            end
            
            app.mat.x = x;
            h(1) = trisurf(tri,v(:,1),v(:,2),v(:,3),x,'parent',app.UIAxes);
            h(2) = trisurf(tri,v(:,1),v(:,2),v(:,3),x,'parent',app.UIAxes_2);
            set(h,'facecolor','interp','edgecolor','none');
            
       
            vv = fieldnames(V.geo);
            ii = find(contains(vv,'Mesh_')&~strcmp(vv,'Mesh_ventricles')&~strcmp(vv,'Mesh_Torso'));
            
            A = V.geo.(vv{ii(1)});
            B = V.geo.(['Nodes_',vv{ii(1)}(6:end)]);
            A(cellfun(@isempty,A))=[];
            B(cellfun(@isempty,B))=[];
            if iscell(A)
                for j = 1:length(A)
                    a = V.geo.Name{j};
                    eval(['V.geo.Mesh_',a,'=A{j};']);
                    eval(['V.geo.Nodes_',a,'=B{j};']);
                end
                V.geo = rmfield(V.geo,vv{ii(1)});
                vv = fieldnames(V.geo);
                ii = find(contains(vv,'Mesh_')&~strcmp(vv,'Mesh_ventricles')&~strcmp(vv,'Mesh_Torso'));
                
            else
                clear A B
            end
            app.mat.V = V;

                    
            if ~isempty(ii)
                hold(app.UIAxes,'on')
                hold(app.UIAxes_2,'on')
                hA1 = gobjects(1,length(ii));
                hA2 = gobjects(1,length(ii));
                for i = 1:length(ii)
                    tri = V.geo.(vv{ii(i)});
                    v = V.geo.(['Nodes_',vv{ii(i)}(6:end)]);
                    hA1(i) = trisurf(tri,v(:,1),v(:,2),v(:,3),1,'parent',app.UIAxes);
                    hA2(i) = trisurf(tri,v(:,1),v(:,2),v(:,3),1,'parent',app.UIAxes_2);
                end
            end
            set([hA1,hA2],'facecolor',[1 1 1]*.85,'edgecolor','none');
            
            axis([app.UIAxes,app.UIAxes_2],'off','image');
            %             set([app.UIAxes,app.UIAxes_2],'position',[0 0 1 1])
            camlight(app.UIAxes,'head')
            camlight(app.UIAxes_2,'head')
 
            cmap = app.mat.cmap;
            colormap(app.UIAxes,cmap);
            colormap(app.UIAxes_2,cmap);
            
            set([app.UIAxes,app.UIAxes_2],'clim',[min(x) max(x)]);
            cb = colorbar(app.UIAxes,'location','southoutside','Units','normalized');
            cb.Position = [0.2 0 .6 .06];
            title(cb,app.mat.cb_title);
            
            cb = colorbar(app.UIAxes_2,'location','southoutside','Units','normalized');
            cb.Position = [0.2 0 .6 .06];
            title(cb,app.mat.cb_title);
            
            title(app.UIAxes,[]);title(app.UIAxes_2,[]);
            app.Edit_AT_min.Value = min(x);
            app.Edit_AT_max.Value = max(x);
            
            app.mat.view_1 = app.UIAxes.View;
            app.mat.view_2 = app.UIAxes_2.View;
            
        end

        % Value changed function: Edit_AT_min
        function Edit_AT_minValueChanged(app, event)
            value = app.Edit_AT_min.Value;
            app.UIAxes.CLim(1) = value;
            app.UIAxes_2.CLim(1) = value;
        end

        % Value changed function: Edit_AT_max
        function Edit_AT_maxValueChanged(app, event)
            value = app.Edit_AT_max.Value;
            app.UIAxes.CLim(2) = value;
            app.UIAxes_2.CLim(2) = value;
        end

        % Callback function
        function ResetLightButtonPushed(app, event)
            cc = findobj(app.UIFigure,'type','light');
            delete(cc)
            camlight(app.UIAxes,'head')
            camlight(app.UIAxes_2,'head')
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            hf = figure;
            set(hf,'color','w')
            
            if app.Switch.Value == '1'
                ax = gca;
                hold(ax(1),'on');
                set(ax(1),'view',app.UIAxes.View)
                
            else
                ax(1) = subplot(121);
                ax(2) = subplot(122);
                hold(ax(1),'on');hold(ax(2),'on')
                set(ax(1),'view',app.UIAxes.View)
                set(ax(2),'view',app.UIAxes_2.View)
            end
            
            if app.Switch.Value == '1'
                set(hf,'units','centimeters','paperunits','centimeters','position',[3 3 12 12],'paperPosition',[0 0 12 12],'papersize',[12 12])
                set(ax(1),'position',[0 0 1 1])
                
            else
                set(hf,'units','centimeters','paperunits','centimeters','position',[3 3 24 12],'paperPosition',[0 0 24 12],'papersize',[24 12])
                set(ax(1),'position',[0 0 0.5 1])
                set(ax(2),'position',[0.5 0 0.5 1])
            end
                     
            V = app.mat.V;
            tri = V.geo.Mesh_ventricles;
            v = V.geo.Nodes_ventricles;
            x = app.mat.x;
            
            h(1) = trisurf(tri,v(:,1),v(:,2),v(:,3),1,'parent',ax(1));
            h2(1) = trisurf(tri,v(:,1),v(:,2),v(:,3),x,'parent',ax(1));
            
            if app.Switch.Value == '2'
                h(2) = trisurf(tri,v(:,1),v(:,2),v(:,3),1,'parent',ax(2));
                h2(2) = trisurf(tri,v(:,1),v(:,2),v(:,3),x,'parent',ax(2));
            end
            
            set(h2(isgraphics(h2)),'facecolor','interp','edgecolor','none');
            set(h(isgraphics(h)),'facecolor',[1 1 1]*.55,'edgecolor','none','FaceLighting','none');

            vv = fieldnames(V.geo);
            ii = find(contains(vv,'Mesh_')&~strcmp(vv,'Mesh_ventricles')&~strcmp(vv,'Mesh_Torso'));
            if ~isempty(ii)
                hA1 = gobjects(1,length(ii));
                hA2 = gobjects(1,length(ii));
                for i = 1:length(ii)
                    tri = V.geo.(vv{ii(i)});
                    v = V.geo.(['Nodes_',vv{ii(i)}(6:end)]);
                    hA1(i) = trisurf(tri,v(:,1),v(:,2),v(:,3),1,'parent',ax(1));
                    if app.Switch.Value == '2'
                        hA2(i) = trisurf(tri,v(:,1),v(:,2),v(:,3),1,'parent',ax(2));
                    end
                end
            end
            set([hA1,hA2(isgraphics(hA2))],'facecolor',[1 1 1]*.85,'edgecolor','none');
            
            cmap = app.mat.cmap;
            colormap(ax(1),cmap);
            
            if app.ColorbarCheckBox.Value==1
                cb = colorbar(ax(1),'location','southoutside','Units','normalized');
                cb.Position = [0.2 0.1 .6 .03];
                title(cb(1),app.mat.cb_title);
                if app.Switch.Value == '2'
                    colormap(ax(2),cmap);
                    cb(2) = colorbar(ax(2),'location','southoutside','Units','normalized');
                    cb(2).Position = [0.2 0.1 .6 .03];
                    title(cb(2),app.mat.cb_title);
                end
                set(cb,'FontSize',11)
            end
                
            
            axis(ax,'off','image');


            % light
            v = app.LightDropDown.Value;
            cc = findobj(ax,'type','light');
            delete(cc)
            for i = 1:length(ax)
                switch v
                    case 'Light H'
                        camlight(ax(i),'headlight');
                    case 'Light R'
                        camlight(ax(i),'right');
                    case 'Light L'
                        camlight(ax(i),'left');
                    case 'Light L+R'
                        camlight(ax(i),'left');
                        camlight(ax(i),'right');
                end
            end
            
            f = [app.mat.filepath,app.mat.filename(1:end-4)];
            [f,p,ind] = uiputfile({'*.mp4';'*.avi'},'Save As',f);
            fname = [p,f];
            fname=fname(1:end-4);
            switch ind
                case 2
                    v = VideoWriter(fname);
                case 1
                    v = VideoWriter(fname,'MPEG-4');
            end
            clear ind
            v.Quality = app.QualEditField.Value;
            v.FrameRate = app.FPSEditField.Value;
            open(v);
            
            N = 100;
            xstep = linspace(min(x),max(x),N);
            for i = 1:length(xstep)
                c = x(:);
                c(c > xstep(i)) = nan;
                h2(1).FaceVertexCData = c;
                if app.Switch.Value == '2'
                    h2(2).FaceVertexCData = c;
                end
                set(ax,'Clim',[app.Edit_AT_min.Value app.Edit_AT_max.Value])
                frame = getframe(hf);
                writeVideo(v,frame);
            end
            
            if app.RotationCheckBox.Value==1
            axis(ax,'vis3d');
            if length(ax)>1
            set(findobj(ax(2),'type','patch'),'visible','off')
            end
            for i = 1 : 3*N
                cc = findobj(ax,'type','light');
                delete(cc)
                
                camlight(ax(1),'head')
%                 camlight(ax(2),'head')
                
                set(ax(1),'view',get(ax(1),'view')+[360/N/3 0])
%                 set(ax(2),'view',get(ax(2),'view')+[360/N/3 0])
                frame = getframe(hf);
                writeVideo(v,frame);
            end
            end
            close(v)
            annotation('textbox','string','Video Recorded','position',[.02 .95 .8 .05],'linestyle','none','fontsize',13)
            
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if isequal(app.Switch.Value,'1')
                set(findobj(app.UIAxes_2,'type','patch'),'visible','off')
            else
                set(findobj(app.UIAxes_2,'type','patch'),'visible','on')
            end
            
        end

        % Value changed function: LightDropDown
        function LightDropDownValueChanged(app, event)
            v = app.LightDropDown.Value;
            cc = findobj([app.UIAxes,app.UIAxes_2],'type','light');
            delete(cc)
            switch v
                case 'Light H'
                    camlight(app.UIAxes,'headlight');
                    camlight(app.UIAxes_2,'headlight');
                case 'Light R'
                    camlight(app.UIAxes,'right');
                    camlight(app.UIAxes_2,'right');
                case 'Light L'
                    camlight(app.UIAxes,'left');
                    camlight(app.UIAxes_2,'left');
                case 'Light L+R'
                    camlight(app.UIAxes,'left');
                    camlight(app.UIAxes,'right');
                    camlight(app.UIAxes_2,'left');
                    camlight(app.UIAxes_2,'right');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ATEditFieldLabel = uilabel(app.UIFigure);
            app.ATEditFieldLabel.Text = 'AT';
            app.Edit_AT_min = uieditfield(app.UIFigure, 'numeric');
            app.Edit_AT_min.ValueChangedFcn = createCallbackFcn(app, @Edit_AT_minValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '-';
            app.Edit_AT_max = uieditfield(app.UIFigure, 'numeric');
            app.Edit_AT_max.ValueChangedFcn = createCallbackFcn(app, @Edit_AT_maxValueChanged, true);
            app.VideoButton = uibutton(app.UIFigure, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'1', '2'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '2';
            app.ParamDropDownLabel = uilabel(app.UIFigure);
            app.ParamDropDownLabel.Text = 'Param';
            app.ParamDropDown = uidropdown(app.UIFigure);
            app.ParamDropDown.Items = {'AT-from ATmin', 'AT-from spike', 'RT'};
            app.ParamDropDown.Value = 'AT-from ATmin';
            app.FPSEditFieldLabel = uilabel(app.UIFigure);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.FPSEditField.Value = 30;
            app.QualEditFieldLabel = uilabel(app.UIFigure);
            app.QualEditFieldLabel.Text = 'Qual.';
            app.QualEditField = uieditfield(app.UIFigure, 'numeric');
            app.QualEditField.Value = 100;
            app.LightDropDown = uidropdown(app.UIFigure);
            app.LightDropDown.Items = {'Light H', 'Light L', 'Light R', 'Light L+R'};
            app.LightDropDown.ValueChangedFcn = createCallbackFcn(app, @LightDropDownValueChanged, true);
            app.LightDropDown.Value = 'Light H';
            app.RotationCheckBox = uicheckbox(app.UIFigure);
            app.RotationCheckBox.Text = 'Rotation';
            app.RotationCheckBox.Value = true;
            app.ColorbarCheckBox = uicheckbox(app.UIFigure);
            app.ColorbarCheckBox.Text = 'Colorbar';
            app.ColorbarCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Video_AT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end